classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlayButton                   matlab.ui.control.Button
        RewindButton                 matlab.ui.control.Button
        ForwardButton                matlab.ui.control.Button
        SearchButton                 matlab.ui.control.Button
        CurrentFrameEditFieldLabel   matlab.ui.control.Label
        CurrentFrameEditField        matlab.ui.control.NumericEditField
        PreviousFrameEditFieldLabel  matlab.ui.control.Label
        PreviousFrameEditField       matlab.ui.control.NumericEditField
        FrametoseeEditFieldLabel     matlab.ui.control.Label
        FrametoseeEditField          matlab.ui.control.NumericEditField
        EnterButton                  matlab.ui.control.Button
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        SliderLabel                  matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        obj = 0; % will contain video object of whatever video user chooses
        Pause = true;
        curr_frame = 1;
        prev_frame = 1;
    end
    
    methods (Access = public)
        % this function plays a video by reading every frame, depending
        % when user pauses or plays a video
        function play_video(app)
            % checks whether there are any more frames left to read
            while hasFrame(app.obj)
                app.prev_frame = app.curr_frame;
                if app.Pause == true
                    break
                end
                % reads next frame
                vidFrame = readFrame(app.obj);
                app.curr_frame = app.curr_frame + 1;
                % displays frame on axes
                %image(vidFrame, 'Parent', app.UIAxes);
                image(vidFrame, "Parent", app.UIAxes, "XData", [1 app.UIAxes.Position(3)], "YData", [1 app.UIAxes.Position(4)]);
                set(app.UIAxes, 'Visible', 'off');
                %app.UIAxes.Visible = "off";
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
                app.Slider.Value = app.curr_frame;
            end
            if ~hasFrame(app.obj)
                app.PlayButton.Text = "Play Again";
                app.PlayButton.BackgroundColor = [1 0 0];
                app.FrametoseeEditField.Visible = 'on';
                app.FrametoseeEditFieldLabel.Visible = 'on';
                app.EnterButton.Visible = 'on';
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
                app.Pause = false;
            end
        end 
        
        % this function converts a mp4 file to an avi file by 
        % reading every frame from the mp4 file and writing those frames
        % to an avi file
        function convert_mp4_to_avi(~, mp4_filename)
            reader = VideoReader(mp4_filename);
            cell = extractBetween(mp4_filename, 1, strlength(mp4_filename) - 4); 
            new_filename = strcat(cell{1}, '.avi');
            writer = VideoWriter(new_filename);
            writer.FrameRate = 60;
            open(writer);
            time = 0;
            f = waitbar(time, 'Please wait...');
            while hasFrame(reader)
                img = readFrame(reader);
                writeVideo(writer, img);
                time = time + 1;
                waitbar(time/reader.NumFrames, f, 'converting mp4 to avi');
            end
            close(f);
            close(writer);
        end
        
        % this function checks whether a given file the user clicks on
        % is in the current directory of the app
        function results = is_member(~, filename, file_list)
            is_in = false;
            for n = 1 : length(file_list)
                if strcmp(filename, file_list{n})
                    is_in = true;
                    break;
                end
            end
            results = is_in;
        end
        
        function choose_coordinate(app)
            % this function will be used to decide whether a coordinate
            % point that the user clicks on is inside the image
            while true
                [x, y, button] = ginput(1);
                if is_coordinate_valid(app, x, y, app.UIAxes.InnerPosition(3), app.UIAxes.InnerPosition(4)) && (button ~= 3) 
                    app.xEditField.Value = x;
                    app.yEditField.Value = y;
                    draw_circle(app, x, y, 20);
                end
                % click on right mouse button to stop gathering coordinates
                if button == 3
                    break;
                end
               
            end
        end
        
        function results = is_coordinate_valid(~, x, y, width, height)
            is_valid = false;
            x_rect_coordinate = 1;
            y_rect_coordinate = 1;
            if (x >= x_rect_coordinate) && (x <= x_rect_coordinate + width)
                if (y >= y_rect_coordinate) && (y <= y_rect_coordinate + height)
                    is_valid = true;
                end
            end
            results = is_valid;
        end
        
        function draw_circle(app, x, y, r)
            % x and y are the coordinates of the center of the circle
            % r is the radius of the circle
            % 0.01 is the angle step, bigger values will draw the circle faster but
            % you might notice imperfections (not very smooth)
            ang=0:0.01:2*pi; 
            xp=r*cos(ang);
            yp=r*sin(ang);
            hold on
            plot(app.UIAxes, x+xp, y+yp);
            hold off
        end
            
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % when playing a video
            if strcmp(app.PlayButton.Text, "Play")
                app.PlayButton.Text = "Pause";  
                app.Pause = false;
                app.PlayButton.BackgroundColor = [0 0 1];
                app.FrametoseeEditField.Visible = 'off';
                app.FrametoseeEditFieldLabel.Visible = 'off';
                app.EnterButton.Visible = 'off';
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
                play_video(app);
            % when plausing a video    
            elseif strcmp(app.PlayButton.Text, "Pause") 
                app.PlayButton.Text = "Play";
                app.FrametoseeEditField.Visible = 'on';
                app.FrametoseeEditFieldLabel.Visible = 'on';
                app.EnterButton.Visible = 'on';
                app.Pause = true;
                app.PlayButton.BackgroundColor = [0 1 0];
                app.Slider.Visible = 'on';
                app.Slider.Enable = 'on';
            % when replaying a video    
            else 
                app.PlayButton.Text = "Pause";
                app.PlayButton.BackgroundColor = [0 0 1];
                app.Pause = false;
                app.curr_frame = 0;
                app.prev_frame = 0;
                app.obj.CurrentTime = 0;
                app.FrametoseeEditField.Visible = 'off';
                app.FrametoseeEditFieldLabel.Visible = 'off';
                app.EnterButton.Visible = 'off';
                app.Slider.Enable = 'on';
                app.Slider.Visible = 'on';
                play_video(app);
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            % returns name of the file a user clicks on
            file = uigetfile('*.mp4');
            if file == 0
                message = sprintf('No file chosen! \n Consider choosing a file next time');
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                return;
            end
            % returns a cell array of avi files in the current directory
            file_list = dir('*.avi');
            file_list = {file_list.name};
            mp4_file_name = extractBetween(file, 1, strlength(file) - 4);
            % checks whether file user clicked on is available as an avi
            % file
            if ~is_member(app,strcat(mp4_file_name{1}, '.avi'), file_list)
                convert_mp4_to_avi(app, file);
                file = strcat(mp4_file_name{1}, '.avi');
            end
            % displays first frame in axes
            app.obj = VideoReader(file);
            vidFrame = readFrame(app.obj);
            % Display image and stretch to fill axes
            I = imshow(vidFrame, 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            set(app.UIAxes, 'Visible', 'off');
            app.Slider.Visible = 'on';
            app.Slider.Enable = 'on';
            app.Slider.Limits = [1 app.obj.NumFrames];
            
            % ginput triggered here in next three lines
            app.UIFigure.HandleVisibility = 'callback';
            choose_coordinate(app);
            app.UIFigure.HandleVisibility = 'off';
           
            app.PlayButton.Text = "Play";
            app.curr_frame = 1;
            app.prev_frame = 1;
            app.CurrentFrameEditField.Value = app.curr_frame;
            app.PreviousFrameEditField.Value = app.prev_frame;
            app.FrametoseeEditField.Limits = [1 app.obj.NumFrames];
            
        end

        % Button pushed function: RewindButton
        function RewindButtonPushed(app, event)
            % if frame being read is the first frame and user wants to
            % rewind, do nothing
            if app.curr_frame == 1
                frame = read(app.obj, app.curr_frame);
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame;
                image(frame, "Parent", app.UIAxes, "XData", [1 app.UIAxes.Position(3)], "YData", [1 app.UIAxes.Position(4)]);
                set(app.UIAxes, 'Visible', 'off');
    
            else 
                frame = read(app.obj, app.curr_frame - 1);
                if app.curr_frame == app.obj.NumFrames
                    app.PlayButton.Text = 'Play';
                end
                app.curr_frame = app.curr_frame - 1;
                app.prev_frame = app.prev_frame - 1;
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame - 1;
                image(frame, "Parent", app.UIAxes, "XData", [1 app.UIAxes.Position(3)], "YData", [1 app.UIAxes.Position(4)]);
                set(app.UIAxes, 'Visible', 'off');
            end
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            if app.curr_frame < app.obj.NumFrames
                frame = read(app.obj, app.curr_frame + 1);
                app.curr_frame = app.curr_frame + 1;
                app.prev_frame = app.prev_frame + 1;
                app.CurrentFrameEditField.Value = app.curr_frame;
                app.PreviousFrameEditField.Value = app.prev_frame - 1;
                image(frame, "Parent", app.UIAxes, "XData", [1 app.UIAxes.Position(3)], "YData", [1 app.UIAxes.Position(4)]);
                set(app.UIAxes, 'Visible', 'off');
            % if frame being read is the very last frame and user wants to
            % forward, do nothing
            else 
                frame = read(app.obj, app.curr_frame);
                image(frame, "Parent", app.UIAxes, "XData", [1 app.UIAxes.Position(3)], "YData", [1 app.UIAxes.Position(4)]);
                set(app.UIAxes, 'Visible', 'off');
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            % gets frame user wants to read and displays that frame on the
            % axes
            frame = read(app.obj, app.FrametoseeEditField.Value);
            app.curr_frame = app.FrametoseeEditField.Value;
            app.Pause = true;
            if app.FrametoseeEditField.Value == 1
                app.prev_frame = 1;
            else 
                app.prev_frame = app.FrametoseeEditField.Value - 1;
            end
            app.CurrentFrameEditField.Value = app.curr_frame;
            app.PreviousFrameEditField.Value = app.prev_frame;
            app.PlayButton.Text = 'Play';
            image(frame, "Parent", app.UIAxes, "XData", [1 app.UIAxes.Position(3)], "YData", [1 app.UIAxes.Position(4)]);
            set(app.UIAxes, 'Visible', 'off');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = floor(app.Slider.Value);
            disp(value);
            app.curr_frame = value;
            app.prev_frame = value - 1;
            app.CurrentFrameEditField.Value = value;
            app.PreviousFrameEditField.Value = app.prev_frame;
            frame = read(app.obj, value);
            image(frame, "Parent", app.UIAxes, "XData", [1 app.UIAxes.Position(3)], "YData", [1 app.UIAxes.Position(4)]);
            set(app.UIAxes, 'Visible', 'off');
            if (value == app.obj.NumFrames)
                app.PlayButton.Text = "Play Again";
            else 
                app.PlayButton.Text = "Play"; 
            end
        end

        % Key press function: UIFigure
        function SpacebarPress(app, event)
            key = event.Key;
            if key == "space"
                if app.obj ~= 0
                    % when playing a video
                    if strcmp(app.PlayButton.Text, "Play")
                        app.PlayButton.Text = "Pause";  
                        app.Pause = false;
                        app.PlayButton.BackgroundColor = [0 0 1];
                        app.FrametoseeEditField.Visible = 'off';
                        app.FrametoseeEditFieldLabel.Visible = 'off';
                        app.EnterButton.Visible = 'off';
                        app.Slider.Visible = 'on';
                        app.Slider.Enable = 'on';
                        play_video(app);
                    % when plausing a video    
                    elseif strcmp(app.PlayButton.Text, "Pause") 
                        app.PlayButton.Text = "Play";
                        app.FrametoseeEditField.Visible = 'on';
                        app.FrametoseeEditFieldLabel.Visible = 'on';
                        app.EnterButton.Visible = 'on';
                        app.Pause = true;
                        app.PlayButton.BackgroundColor = [0 1 0];
                        app.Slider.Visible = 'on';
                        app.Slider.Enable = 'on';
                    % when replaying a video    
                    else 
                        app.PlayButton.Text = "Pause";
                        app.PlayButton.BackgroundColor = [0 0 1];
                        app.Pause = false;
                        app.curr_frame = 0;
                        app.prev_frame = 0;
                        app.obj.CurrentTime = 0;
                        app.FrametoseeEditField.Visible = 'off';
                        app.FrametoseeEditFieldLabel.Visible = 'off';
                        app.EnterButton.Visible = 'off';
                        app.Slider.Enable = 'on';
                        app.Slider.Visible = 'on';
                        play_video(app);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @SpacebarPress, true);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RewindButton = uibutton(app.UIFigure, 'push');
            app.RewindButton.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.RewindButton.Text = 'Rewind';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.CurrentFrameEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.CurrentFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrameEditField.Editable = 'off';
            app.PreviousFrameEditFieldLabel = uilabel(app.UIFigure);
            app.PreviousFrameEditFieldLabel.Text = 'Previous Frame';
            app.PreviousFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreviousFrameEditField.Editable = 'off';
            app.FrametoseeEditFieldLabel = uilabel(app.UIFigure);
            app.FrametoseeEditFieldLabel.Text = 'Frame # to see';
            app.FrametoseeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Editable = 'off';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Editable = 'off';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end